classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        VelocidadinicialSpinnerLabel    matlab.ui.control.Label
        VelocidadinicialSpinner         matlab.ui.control.Spinner
        ngulodetiroinicialSpinnerLabel  matlab.ui.control.Label
        ngulodetiroinicialSpinner       matlab.ui.control.Spinner
        AlturainicialSpinnerLabel       matlab.ui.control.Label
        AlturainicialSpinner            matlab.ui.control.Spinner
        MasadelproyectilSpinnerLabel    matlab.ui.control.Label
        MasadelproyectilSpinner         matlab.ui.control.Spinner
        ValoresLabel                    matlab.ui.control.Label
        UnidadesLabel                   matlab.ui.control.Label
        TimestepdtSpinnerLabel          matlab.ui.control.Label
        TimestepdtSpinner               matlab.ui.control.Spinner
        ExponentenSpinnerLabel          matlab.ui.control.Label
        ExponentenSpinner               matlab.ui.control.Spinner
        AceleracindelagravedadSpinnerLabel  matlab.ui.control.Label
        AceleracindelagravedadSpinner   matlab.ui.control.Spinner
        CoeficientebSpinnerLabel        matlab.ui.control.Label
        CoeficientebSpinner             matlab.ui.control.Spinner
        CoeficientebPanel               matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        CoeficientedearrastreCSpinnerLabel  matlab.ui.control.Label
        CoeficientedearrastreCSpinner   matlab.ui.control.Spinner
        DensidaddelairerhoSpinnerLabel  matlab.ui.control.Label
        DensidaddelairerhoSpinner       matlab.ui.control.Spinner
        reafrontaldelproyectilASpinnerLabel  matlab.ui.control.Label
        reafrontaldelproyectilASpinner  matlab.ui.control.Spinner
        kgm3Label                       matlab.ui.control.Label
        m2Label                         matlab.ui.control.Label
        VermsButton                     matlab.ui.control.Button
        msLabel                         matlab.ui.control.Label
        gradosLabel                     matlab.ui.control.Label
        metrosLabel                     matlab.ui.control.Label
        kilogramosLabel                 matlab.ui.control.Label
        segundosLabel                   matlab.ui.control.Label
        ms2Label                        matlab.ui.control.Label
        CristinAldoSandovalSurezLabel   matlab.ui.control.Label
        BenjamnAntonioVelascoGuzmnLabel  matlab.ui.control.Label
        GraficarButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        DatosdelproyectilconresistenciadelairePanel  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        AceleracinenxLabel              matlab.ui.control.Label
        VelocidadenxLabel               matlab.ui.control.Label
        VelocidadenyLabel               matlab.ui.control.Label
        AlturaLabel                     matlab.ui.control.Label
        AlcanceLabel                    matlab.ui.control.Label
        TiempoLabel                     matlab.ui.control.Label
        AlturamximaSRLabel              matlab.ui.control.Label
        AlcancemximoSRLabel             matlab.ui.control.Label
        AccelerationXLabel              matlab.ui.control.Label
        AccelerationYLabel              matlab.ui.control.Label
        VelocityXLabel                  matlab.ui.control.Label
        VelocityYLabel                  matlab.ui.control.Label
        HeightLabel                     matlab.ui.control.Label
        DistanceLabel                   matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        MaxHeightLabel                  matlab.ui.control.Label
        MaxDistanceLabel                matlab.ui.control.Label
        AceleracinenyLabel              matlab.ui.control.Label
        SRsinresistenciaaerodinmicaLabel  matlab.ui.control.Label
        LimpiargrficaButton             matlab.ui.control.Button
        DetenerButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        stop_animations = 0; % flag to stop animations
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stop_animations = 0;
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            app.LimpiargrficaButton.Enable="off";
            app.DetenerButton.Enable="on";
            
            % GUI variables
            initial_velocity = app.VelocidadinicialSpinner.Value;
            theta_deg = app.ngulodetiroinicialSpinner.Value;
            initial_pos_y = app.AlturainicialSpinner.Value;
            gravity = app.AceleracindelagravedadSpinner.Value;
            dt = app.TimestepdtSpinner.Value;
            b_coeff = app.CoeficientebSpinner.Value;
            mass = app.MasadelproyectilSpinner.Value;
            n_exp = app.ExponentenSpinner.Value;
            ui_axes = app.UIAxes;
            
            if app.CoeficientebPanel.Visible == "on"
                % data for b
                drag_coeff = app.CoeficientedearrastreCSpinner.Value;
                frontal_area = app.reafrontaldelproyectilASpinner.Value;
                air_density = app.DensidaddelairerhoSpinner.Value;
                
                b_coeff = 0.5 * drag_coeff * air_density * frontal_area;
                app.CoeficientebSpinner.Value = b_coeff;
            end
                        
            % GUI labels
            maxHeightLabel = app.MaxHeightLabel;
            maxDistanceLabel = app.MaxDistanceLabel;
            accXLabel = app.AccelerationXLabel;
            accYLabel = app.AccelerationYLabel;
            velXLabel = app.VelocityXLabel;
            velYLabel = app.VelocityYLabel;
            heightLabel = app.HeightLabel;
            distanceLabel = app.DistanceLabel;
            timeLabel = app.TimeLabel;
            
            total_time = 0;
            
            % convertions
            theta = deg2rad(theta_deg);
            
            % initial values for x axis
            pos_x = 0;
            acc_x = 0;
            vel_x = initial_velocity * cos(theta);
            acc_x_past = acc_x;
            
            % initial values for y axis
            pos_y = initial_pos_y;
            acc_y = -gravity;
            vel_y = initial_velocity * sin(theta);
            acc_y_past = acc_y;
            
            % initial values for the plot without air resistance
            pos_y_no_air = initial_pos_y;
            pos_y_max = (initial_velocity ^ 2 * sin(theta) ^ 2) / (2 * gravity) + initial_pos_y;
            % TODO fix this
            pos_x_max = (initial_velocity ^ 2 * cos(theta) / gravity) * (sin(theta) + sqrt(sin(theta) ^ 2 + 2 * gravity * initial_pos_y / initial_velocity ^ 2));
            
            maxHeightLabel.Text = sprintf("%.2f m", pos_y_max);
            maxDistanceLabel.Text = sprintf("%.2f m", pos_x_max);
            drawnow;
            
            % plot constraints
            legend(ui_axes, "hide");
            hold(ui_axes, "on");
            max_limit = max([pos_y_max, pos_x_max]);
            axis(ui_axes, [-1, max_limit + 1, -1, max_limit + 1]);
            axis(ui_axes, "square");
            
            random_color_vector = randi([2 8], 1, 3) / 10;
            random_color.r = random_color_vector(1);
            random_color.g = random_color_vector(2);
            random_color.b = random_color_vector(3);
            
            animation_air_resistance = animatedline(ui_axes, "Color", [random_color.r, random_color.g, random_color.b]);
            animation_no_air = animatedline(ui_axes, "Color", [random_color.r - 0.2, random_color.g - 0.2, random_color.b - 0.2]);
            addpoints(animation_air_resistance, pos_x, pos_y);
            addpoints(animation_no_air, pos_x, pos_y_no_air);
            
            plt_point_air = plot(ui_axes, pos_x, pos_y, "Marker", 'o', "Color", [random_color.r + 0.2, random_color.g + 0.2, random_color.b + 0.2]);
            plt_point_no_air = plot(ui_axes, pos_x, pos_y_no_air, "Marker", 'o', "Color", [random_color.r - 0.2 random_color.g - 0.2, random_color.b - 0.2]);
            
            continue_animation = 1;
            
            while continue_animation && ~app.stop_animations
                
                % Calculate values for x axis in this iteration
                pos_x = pos_x + vel_x * dt + 0.5 * acc_x * dt ^ 2;
                acc_x = - (b_coeff / mass) * sign(vel_x) * abs(vel_x) .^ n_exp;
                vel_x = vel_x + 0.5 * (acc_x + acc_x_past) * dt;
                
                % Calculate values for y axis in this iteration
                if pos_y >= 0 % it's worthless calculating y pos if it has already touched the floor
                    pos_y = pos_y + vel_y * dt + 0.5 * acc_y * dt ^ 2;
                    acc_y = - gravity - (b_coeff / mass) * sign(vel_y) * abs(vel_y) .^ n_exp;
                    vel_y = vel_y + 0.5 * (acc_y + acc_y_past) * dt;
                    
                    % add data to plot
                    addpoints(animation_air_resistance, pos_x, pos_y);
                    plt_point_air.XData = pos_x;
                    plt_point_air.YData = pos_y;
                    
                    % show data in the table
                    accXLabel.Text = sprintf("%.2f m/s^2", acc_x_past);
                    accYLabel.Text = sprintf("%.2f m/s^2", acc_y_past);
                    velXLabel.Text = sprintf("%.2f m/s", vel_x);
                    velYLabel.Text = sprintf("%.2f m/s", vel_y);
                    heightLabel.Text = sprintf("%.2f m", pos_y);
                    distanceLabel.Text = sprintf("%.2f m", pos_x);
                    timeLabel.Text = sprintf("%.2f s", total_time);
                else
                    if pos_y_no_air <= 0 % if both points have reached the floor
                        continue_animation = 0;
                    end
                end
                
                if pos_y_no_air < 0
                    if pos_y <= 0 % if both points have reached the floor
                        continue_animation = 0;
                    end
                else
                    % Calculate values for y axis in this iteration
                    pos_y_no_air = pos_x * tan(theta) - ((gravity * pos_x ^ 2) / (2 * (initial_velocity * cos(theta)) ^ 2)) + initial_pos_y;
                
                    addpoints(animation_no_air, pos_x, pos_y_no_air);
                    plt_point_no_air.XData = pos_x;
                    plt_point_no_air.YData = pos_y_no_air;
                end
                
                acc_x_past = acc_x;
                acc_y_past = acc_y;
                total_time = total_time + dt;
                
                pause(0.1);
            end
            app.stop_animations = 0;
            legend(ui_axes, [plt_point_no_air, plt_point_air], ["Sin resistencia del aire" "Con resistencia del aire"]);
            
            app.DetenerButton.Text = "Detener";
            app.DetenerButton.Enable = "off";
            
            app.LimpiargrficaButton.Enable="on";
        end

        % Button pushed function: VermsButton
        function VermsButtonPushed(app, event)
            if app.CoeficientebPanel.Visible == "off"
                app.CoeficientebPanel.Visible = "on";
                app.VermsButton.Text = "Ver menos";
            else
                app.CoeficientebPanel.Visible = "off";
                app.VermsButton.Text = "Ver más";
            end
        end

        % Button pushed function: LimpiargrficaButton
        function LimpiargrficaButtonPushed(app, event)
            app.UIAxes.cla;
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            app.DetenerButton.Text = "Espere...";
            app.DetenerButton.Enable = "off";
            app.stop_animations = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.VelocidadinicialSpinnerLabel = uilabel(app.GridLayout2);
            app.VelocidadinicialSpinnerLabel.Text = 'Velocidad inicial';
            app.VelocidadinicialSpinner = uispinner(app.GridLayout2);
            app.VelocidadinicialSpinner.Limits = [0 100000];
            app.VelocidadinicialSpinner.Value = 14;
            app.ngulodetiroinicialSpinnerLabel = uilabel(app.GridLayout2);
            app.ngulodetiroinicialSpinnerLabel.Text = 'Ángulo de tiro inicial';
            app.ngulodetiroinicialSpinner = uispinner(app.GridLayout2);
            app.ngulodetiroinicialSpinner.Limits = [0 90];
            app.ngulodetiroinicialSpinner.Value = 60;
            app.AlturainicialSpinnerLabel = uilabel(app.GridLayout2);
            app.AlturainicialSpinnerLabel.Text = 'Altura inicial';
            app.AlturainicialSpinner = uispinner(app.GridLayout2);
            app.AlturainicialSpinner.Limits = [0 100000];
            app.MasadelproyectilSpinnerLabel = uilabel(app.GridLayout2);
            app.MasadelproyectilSpinnerLabel.Text = 'Masa del proyectil';
            app.MasadelproyectilSpinner = uispinner(app.GridLayout2);
            app.MasadelproyectilSpinner.Limits = [0 100000];
            app.MasadelproyectilSpinner.Value = 20.5;
            app.ValoresLabel = uilabel(app.GridLayout2);
            app.ValoresLabel.Text = 'Valores';
            app.UnidadesLabel = uilabel(app.GridLayout2);
            app.UnidadesLabel.Text = 'Unidades';
            app.TimestepdtSpinnerLabel = uilabel(app.GridLayout2);
            app.TimestepdtSpinnerLabel.Text = 'Timestep (dt)';
            app.TimestepdtSpinner = uispinner(app.GridLayout2);
            app.TimestepdtSpinner.Limits = [0 10];
            app.TimestepdtSpinner.Value = 0.05;
            app.ExponentenSpinnerLabel = uilabel(app.GridLayout2);
            app.ExponentenSpinnerLabel.Text = 'Exponente n';
            app.ExponentenSpinner = uispinner(app.GridLayout2);
            app.ExponentenSpinner.Limits = [-4 4];
            app.ExponentenSpinner.Value = 2;
            app.AceleracindelagravedadSpinnerLabel = uilabel(app.GridLayout2);
            app.AceleracindelagravedadSpinnerLabel.Text = 'Aceleración de la gravedad';
            app.AceleracindelagravedadSpinner = uispinner(app.GridLayout2);
            app.AceleracindelagravedadSpinner.Limits = [0 100000];
            app.AceleracindelagravedadSpinner.Value = 9.81;
            app.CoeficientebSpinnerLabel = uilabel(app.GridLayout2);
            app.CoeficientebSpinnerLabel.Text = 'Coeficiente b';
            app.CoeficientebSpinner = uispinner(app.GridLayout2);
            app.CoeficientebSpinner.Limits = [0 2];
            app.CoeficientebSpinner.Value = 0.2879;
            app.CoeficientebPanel = uipanel(app.GridLayout2);
            app.CoeficientebPanel.Title = 'Coeficiente b (se calculará con base a esto, no se tomará el valor de la entrada anterior)';
            app.CoeficientebPanel.Visible = 'off';
            app.GridLayout3 = uigridlayout(app.CoeficientebPanel);
            app.CoeficientedearrastreCSpinnerLabel = uilabel(app.GridLayout3);
            app.CoeficientedearrastreCSpinnerLabel.Text = 'Coeficiente de arrastre (C)';
            app.CoeficientedearrastreCSpinner = uispinner(app.GridLayout3);
            app.CoeficientedearrastreCSpinner.Limits = [0 3];
            app.CoeficientedearrastreCSpinner.Value = 0.47;
            app.DensidaddelairerhoSpinnerLabel = uilabel(app.GridLayout3);
            app.DensidaddelairerhoSpinnerLabel.Text = 'Densidad del aire (rho)';
            app.DensidaddelairerhoSpinner = uispinner(app.GridLayout3);
            app.DensidaddelairerhoSpinner.Limits = [0 3];
            app.DensidaddelairerhoSpinner.Value = 1.225;
            app.reafrontaldelproyectilASpinnerLabel = uilabel(app.GridLayout3);
            app.reafrontaldelproyectilASpinnerLabel.Text = 'Área frontal del proyectil (A)';
            app.reafrontaldelproyectilASpinner = uispinner(app.GridLayout3);
            app.reafrontaldelproyectilASpinner.Limits = [0 100000];
            app.reafrontaldelproyectilASpinner.Value = 1;
            app.kgm3Label = uilabel(app.GridLayout3);
            app.kgm3Label.Text = 'kg/m^3';
            app.m2Label = uilabel(app.GridLayout3);
            app.m2Label.Text = 'm^2';
            app.VermsButton = uibutton(app.GridLayout2, 'push');
            app.VermsButton.ButtonPushedFcn = createCallbackFcn(app, @VermsButtonPushed, true);
            app.VermsButton.Tooltip = {'Cálculo del coeficiente b'};
            app.VermsButton.Text = 'Ver más';
            app.msLabel = uilabel(app.GridLayout2);
            app.msLabel.Text = 'm/s';
            app.gradosLabel = uilabel(app.GridLayout2);
            app.gradosLabel.Text = 'grados';
            app.metrosLabel = uilabel(app.GridLayout2);
            app.metrosLabel.Text = 'metros';
            app.kilogramosLabel = uilabel(app.GridLayout2);
            app.kilogramosLabel.Text = 'kilogramos';
            app.segundosLabel = uilabel(app.GridLayout2);
            app.segundosLabel.Text = 'segundos';
            app.ms2Label = uilabel(app.GridLayout2);
            app.ms2Label.Text = 'm/s^2';
            app.CristinAldoSandovalSurezLabel = uilabel(app.GridLayout2);
            app.CristinAldoSandovalSurezLabel.Text = 'Cristián Aldo Sandoval Suárez';
            app.BenjamnAntonioVelascoGuzmnLabel = uilabel(app.GridLayout2);
            app.BenjamnAntonioVelascoGuzmnLabel.Text = 'Benjamín Antonio Velasco Guzmán';
            app.GraficarButton = uibutton(app.GridLayout2, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Posición del proyectil')
            xlabel(app.UIAxes, 'Alcance (x)')
            ylabel(app.UIAxes, 'Altura (y)')
            app.DatosdelproyectilconresistenciadelairePanel = uipanel(app.GridLayout2);
            app.DatosdelproyectilconresistenciadelairePanel.Title = 'Datos del proyectil con resistencia del aire';
            app.GridLayout4 = uigridlayout(app.DatosdelproyectilconresistenciadelairePanel);
            app.AceleracinenxLabel = uilabel(app.GridLayout4);
            app.AceleracinenxLabel.Text = 'Aceleración en x';
            app.VelocidadenxLabel = uilabel(app.GridLayout4);
            app.VelocidadenxLabel.Text = 'Velocidad en x';
            app.VelocidadenyLabel = uilabel(app.GridLayout4);
            app.VelocidadenyLabel.Text = 'Velocidad en y';
            app.AlturaLabel = uilabel(app.GridLayout4);
            app.AlturaLabel.Text = 'Altura';
            app.AlcanceLabel = uilabel(app.GridLayout4);
            app.AlcanceLabel.Text = 'Alcance';
            app.TiempoLabel = uilabel(app.GridLayout4);
            app.TiempoLabel.Text = 'Tiempo';
            app.AlturamximaSRLabel = uilabel(app.GridLayout4);
            app.AlturamximaSRLabel.Text = 'Altura máxima SR';
            app.AlcancemximoSRLabel = uilabel(app.GridLayout4);
            app.AlcancemximoSRLabel.Text = 'Alcance máximo SR';
            app.AccelerationXLabel = uilabel(app.GridLayout4);
            app.AccelerationXLabel.Text = '';
            app.AccelerationYLabel = uilabel(app.GridLayout4);
            app.AccelerationYLabel.Text = '';
            app.VelocityXLabel = uilabel(app.GridLayout4);
            app.VelocityXLabel.Text = '';
            app.VelocityYLabel = uilabel(app.GridLayout4);
            app.VelocityYLabel.Text = '';
            app.HeightLabel = uilabel(app.GridLayout4);
            app.HeightLabel.Text = '';
            app.DistanceLabel = uilabel(app.GridLayout4);
            app.DistanceLabel.Text = '';
            app.TimeLabel = uilabel(app.GridLayout4);
            app.TimeLabel.Text = '';
            app.MaxHeightLabel = uilabel(app.GridLayout4);
            app.MaxHeightLabel.Text = '';
            app.MaxDistanceLabel = uilabel(app.GridLayout4);
            app.MaxDistanceLabel.Text = '';
            app.AceleracinenyLabel = uilabel(app.GridLayout4);
            app.AceleracinenyLabel.Text = 'Aceleración en y';
            app.SRsinresistenciaaerodinmicaLabel = uilabel(app.GridLayout2);
            app.SRsinresistenciaaerodinmicaLabel.Text = 'SR: sin resistencia aerodinámica';
            app.LimpiargrficaButton = uibutton(app.GridLayout2, 'push');
            app.LimpiargrficaButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiargrficaButtonPushed, true);
            app.LimpiargrficaButton.Text = 'Limpiar gráfica';
            app.DetenerButton = uibutton(app.GridLayout2, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Enable = 'off';
            app.DetenerButton.Text = 'Detener';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end